classdef Covid19Pune < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BackButton            matlab.ui.control.Button
        PuneCovid19DataLabel  matlab.ui.control.Label
        graph                 matlab.ui.control.UIAxes
        graph2                matlab.ui.control.UIAxes
        TextArea              matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t=readtable('covid19mah.xlsx','Sheet',"districts");
            date=t.Date;
            confirmed=t.Confirmed;
            recovered=t.Recovered;
            dth=t.Deceased;
            active=t.Active;
            len=length(date);
            p=pie(app.graph, ...
                [recovered(len-1) dth(len-1) active(len-1)]);
            p(2)
            p(3)
            %set(p(1),'Facecolor','r');
            set(p(1),'Facecolor','g');
            set(p(3),'Facecolor','k');
            set(p(5),'Facecolor','b');
            legend(app.graph,{'Confirmed','Recovered','Deceased','Active'}, ...
                'Location','best');
            app.graph.BackgroundColor=[0.93,0.69,0.13];
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            covid19mah;
        end

        % Callback function
        function showButtonPushed(app, event)
            pie(app.graph,[10 30 50]);
            legend(app.graph);
            app.graph.Color=[0.93,0.69,0.13];
            app.graph.BackgroundColor=[0.93,0.69,0.13];
            app.graph.Box=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.PuneCovid19DataLabel = uilabel(app.UIFigure);
            app.PuneCovid19DataLabel.Text = 'Pune Covid19 Data';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Title')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.graph2 = uiaxes(app.UIFigure);
            title(app.graph2, 'Title')
            xlabel(app.graph2, 'X')
            ylabel(app.graph2, 'Y')
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid19Pune

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end