classdef covid19mah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        covid19                         matlab.ui.Figure
        b1                              matlab.ui.control.Button
        graph                           matlab.ui.control.UIAxes
        selectgraphtypeDropDownLabel    matlab.ui.control.Label
        list                            matlab.ui.control.DropDown
        l1                              matlab.ui.control.Label
        selectgraphtypeDropDownLabel_2  matlab.ui.control.Label
        list2                           matlab.ui.control.DropDown
        group                           matlab.ui.container.ButtonGroup
        ConfirmedButton                 matlab.ui.control.RadioButton
        ActiveButton                    matlab.ui.control.RadioButton
        RecoveredButton                 matlab.ui.control.RadioButton
        CasualtiesButton                matlab.ui.control.RadioButton
        DisplayCitywiseButton           matlab.ui.control.StateButton
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ViewPunepageButton              matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
global t cum_confirmed cum_recovered cum_dth l date;

t=readtable("covid19mah.xlsx",'Sheet','MH_daily_req');
cum_confirmed=t.Cumulative_confirmed;
cum_recovered=t.Cumulative_recovered;
cum_dth=t.Cumulative_dth;
date=t.Date;
l=numel(cum_confirmed);

[avg,latest]=func();
r=rec();
d=dth();
          
app.TextArea.Value=["average growth rate(in last 7 days)= "+avg+" %","latest growth rate ="+latest+" %","Recovery rate= "+r+" %","death rate= "+d+" %"];
    %uialert(app.covid19,"Updated till "+datestr(date(l)),"Info","Icon","info");
    app.Label.Text="Updated till "+datestr(date(l));

function [avg,latest] = func()
    
    grth=zeros(1,l);
    for i=1:l-1
        grth(i)=(cum_confirmed(i+1)-cum_confirmed(i))*100/cum_confirmed(i);
    end
    
    sum=0;
    for i=l-7:l-1
        sum=sum+grth(i);
    end
    
    latest=grth(l-1);
    avg=sum/7;
    
end

function [r]=rec()
 r=cum_recovered(l-1)*100/cum_confirmed(l-1);
end

function [d]=dth()
 d=cum_dth(l-1)*100/cum_confirmed(l-1);
end

        end

        % Button pushed function: b1
        function b1ButtonPushed(app, event)
            t=readtable('covid19mah.xlsx','Sheet',"MH_daily_req");
            date=t.Date;
            confirmed_daily=t.Confirmed;
            recovered_daily=t.Recovered;
            deceased_daily=t.Deceased;
            active_daily=t.Active;
            cum_confirmed=t.Cumulative_confirmed;
            cum_dth=t.Cumulative_dth;
            cum_recovered=t.Cumulative_recovered;
            cum_active=t.Cumulative_active;
            it=app.list.Value;
            val=app.list2.Value;


if app.ConfirmedButton.Value==1  
            if val=="line"
                
                if it=="daily"
                    
                    plot(app.graph,date,confirmed_daily,'r','LineWidth',2);
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'confirmed cases','FontSize',15);
                    title(app.graph,'daily coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    app.graph.NextPlot='add';
                    plot(app.graph,date,confirmed_daily','b.','MarkerSize',15);
                    app.graph.NextPlot='replace';
                    grp();
                
                elseif it== "total"
                    
                    plot(app.graph,date,cum_confirmed,'r','LineWidth',2);
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'confirmed cases','FontSize',15);
                    title(app.graph,'Total coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    app.graph.NextPlot='add';
                    plot(app.graph,date,cum_confirmed,'b.','MarkerSize',15);
                    app.graph.NextPlot='replace';
                    grp();
                else
                    uialert(app.covid19,"Please seleconfirmed_daily an option","Error");
                end
                
            elseif val=="bar"
                
                if it=="daily"
                    
                    bar(app.graph,date,confirmed_daily,0.5,'r');
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'confirmed cases','FontSize',15);
                    title(app.graph,'daily coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    for i=1:numel(confirmed_daily)
                        text(app.graph,date(i),confirmed_daily(i),num2str(confirmed_daily(i)),"Rotation",90,'HorizontalAlignment','left','VerticalAlignment','middle',"FontSize",10);
                    end
                    grp();
                
                elseif it== "total"
                    
                    bar(app.graph,date,cum_confirmed,0.5,'r');
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'confirmed cases','FontSize',15);
                    title(app.graph,'Total coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    ylim(app.graph,[0 (length(cum_confirmed))+1000]);
                    for i=1:numel(cum_confirmed)
                        text(app.graph,date(i),cum_confirmed(i),num2str(cum_confirmed(i)),"Rotation",90,'HorizontalAlignment','left','VerticalAlignment','middle',"FontSize",10);
                    end
                    grp();

                else
                    uialert(app.covid19,"Please seleconfirmed_daily an option","Error");
                end
                
            else
                uialert(app.covid19,"Please seleconfirmed_daily an option","Error");
            end


elseif app.ActiveButton.Value==1
    if val=="line"
        
        
            if it=="daily"
                    plot(app.graph,date,active_daily,'b','LineWidth',2);
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'Aconfirmed_dailyive cases','FontSize',15);
                    title(app.graph,'daily aconfirmed_dailyive coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    app.graph.NextPlot='add';
                    plot(app.graph,date,active_daily,'r.','MarkerSize',5);
                    app.graph.NextPlot='replace';
                    grp();
            elseif it=="total"
                    plot(app.graph,date,cum_active,'b','LineWidth',2);
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'Aconfirmed_dailyive cases','FontSize',15);
                    title(app.graph,'Total Aconfirmed_dailyive coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    app.graph.NextPlot='add';
                    plot(app.graph,date,cum_active','r.','MarkerSize',5);
                    app.graph.NextPlot='replace';
                    grp();
            end
    elseif val=="bar"
        
        
            if it=="daily"
                
                    bar(app.graph,date,active_daily,0.3,'b');
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'active cases','FontSize',15);
                    title(app.graph,'daily active coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    for i=1:numel(active_daily)
                        text(app.graph,date(i),active_daily(i),num2str(active_daily(i)),"Rotation",90,'HorizontalAlignment','left','VerticalAlignment','middle',"FontSize",8);
                    end
                    grp();
            elseif it=="total"
                    bar(app.graph,date,cum_active,0.3,'b');
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'active cases','FontSize',15);
                    title(app.graph,'Total active coronavirus cases count','FontSize',18,'FontWeight',"normal");
%                     for i=1:numel(cum_active)
%                         text(app.graph,date(i),cum_active(i),num2str(cum_active(i)),"Rotation",90,'HorizontalAlignment','left','VerticalAlignment','middle',"FontSize",8);
%                     end
                    grp();
            end
    end


elseif app.RecoveredButton.Value==1
    if val=="line"
        
                
            if it=="daily"
                    plot(app.graph,date,recovered_daily,'g','LineWidth',2);
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'Recovered cases','FontSize',15);
                    title(app.graph,'daily recovered coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    app.graph.NextPlot='add';
                    plot(app.graph,date,recovered_daily','r.','MarkerSize',5);
                    app.graph.NextPlot='replace';
                    grp();
            elseif it=="total"
                    plot(app.graph,date,cum_recovered,'g','LineWidth',2);
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'Recovered cases','FontSize',15);
                    title(app.graph,'Total Recovered coronavirus cases count','FontSize',18,'FontWeight',"normal");
                    app.graph.NextPlot='add';
                    plot(app.graph,date,cum_recovered','r.','MarkerSize',5);
                    app.graph.NextPlot='replace';
                    grp();
            end
        
    elseif val=="bar"
        
                
            if it=="daily"
                
                    bar(app.graph,date,recovered_daily,0.3,'g');
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'recovered cases','FontSize',15);
                    title(app.graph,'Daily coronavirus Recovered cases count','FontSize',18,'FontWeight',"normal");
                    for i=1:numel(recovered_daily)
                        text(app.graph,date(i),recovered_daily(i),num2str(recovered_daily(i)),"Rotation",90,'HorizontalAlignment','left','VerticalAlignment','middle',"FontSize",8);
                    end
                    grp();
            elseif it=="total"
                    bar(app.graph,date,cum_recovered,0.3,'g');
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'recovered cases','FontSize',15);
                    title(app.graph,'Total coronavirus Recovered cases count','FontSize',18,'FontWeight',"normal");
                    for i=1:numel(cum_recovered)
                        text(app.graph,date(i),cum_recovered(i),num2str(cum_recovered(i)),"Rotation",90,'HorizontalAlignment','left','VerticalAlignment','middle',"FontSize",8);
                    end
                    grp();
            end
    end


elseif app.CasualtiesButton.Value==1
    if val=="line"
        
                
            if it=="daily"
                plot(app.graph,date,deceased_daily,'k','LineWidth',2);
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'death count','FontSize',15);
                    title(app.graph,'daily coronavirus deaths count','FontSize',18,'FontWeight',"normal");
                    app.graph.NextPlot='add';
                    plot(app.graph,date,cs','r.','MarkerSize',15);
                    app.graph.NextPlot='replace';
                    grp();
            elseif it=="total"
                    plot(app.graph,date,cum_dth,'k','LineWidth',2);
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'death count','FontSize',15);
                    title(app.graph,'Total coronavirus cases deaths','FontSize',18,'FontWeight',"normal");
                    app.graph.NextPlot='add';
                    plot(app.graph,date,cdateh','r.','MarkerSize',15);
                    app.graph.NextPlot='replace';
                    grp();
            end
            
    elseif val=="bar"
        
                
            if it=="daily"
                
                    bar(app.graph,date,deceased_daily,0.3,'k');
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'deaths','FontSize',15);
                    title(app.graph,'Daily coronavirus deaths','FontSize',18,'FontWeight',"normal");
                    for i=1:numel(deceased_daily)
                        text(app.graph,date(i),deceased_daily(i),num2str(deceased_daily(i)),"Rotation",90,'HorizontalAlignment','left','VerticalAlignment','middle',"FontSize",8);
                    end
                    grp();
            elseif it=="total"
                    bar(app.graph,date,cum_dth,0.3,'k');
                    xlabel(app.graph,'date','FontSize',15);
                    ylabel(app.graph,'deaths','FontSize',15);
                    title(app.graph,'Total coronavirus deaths','FontSize',18,'FontWeight',"normal");
                    for i=1:numel(cum_dth)
                        text(app.graph,date(i),cum_dth(i),num2str(cum_dth(i)),"Rotation",90,'HorizontalAlignment','left','VerticalAlignment','middle',"FontSize",8);
                    end
                    grp();
            end
    end
    
else
    uialert(app.covid19,"please seleconfirmed_daily a category","Error","Icon","error");
end
    
            function []=grp()
                app.graph.BackgroundColor=[0.93,0.69,0.13];
                app.graph.Box=1;
                app.graph.XGrid=1;
                app.graph.YGrid=1;
                app.graph.XMinorGrid=1;
                app.graph.YMinorGrid=1;
            end
        end

        % Value changed function: DisplayCitywiseButton
        function DisplayCitywiseButtonValueChanged(app, event)
            value = app.DisplayCitywiseButton.Value;
            t1=readtable('covid19mah.xlsx','Sheet','MH_district');
            srno=t1.sr;
            confirmed=t1.Confirmed;
            active=t1.Active;
            recovered=t1.Recovered;
            combined=[confirmed';recovered';active'];
            combined'
            city=t1.District;
        if value==1
            app.b1.Visible=0;
            app.DisplayCitywiseButton.Tooltip="press button to close citywise data";
            grp=bar(app.graph,combined',1.4);
            set(grp(1),'Facecolor','r');
            set(grp(2),'Facecolor','g');
            set(grp(3),'Facecolor','b');
            legend(app.graph,'confirmed','recovered','active');
            for i=1:numel(srno)
                text(app.graph,srno(i),confirmed(i),city(i),'Rotation',90,"FontSize",17,'FontWeight',"bold");
            end
            text(app.graph,30,confirmed(1),"Total="+sum(confirmed),'FontSize',18,"FontWeight","bold");
            title(app.graph,"Citywise Confirmed Cases","FontSize",18);
            ylabel(app.graph,"Confirmed Cases",'FontSize',15);
            
        else
            app.b1.Visible=1;
            app.DisplayCitywiseButton.Tooltip="press button to show citywise data";
        end
        end

        % Button pushed function: ViewPunepageButton
        function ViewPunepageButtonPushed(app, event)
            closereq();
            run("Covid19Pune_autoreflow.mlapp");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.covid19 = uifigure('Visible', 'off');
            app.covid19.Name = 'Covid19Mah';
            app.b1 = uibutton(app.covid19, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1ButtonPushed, true);
            app.b1.Tooltip = {'Display Graph of selected Category'};
            app.b1.Text = 'Show graph';
            app.graph = uiaxes(app.covid19);
            title(app.graph, 'Title')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.graph.Visible = 'off';
            app.selectgraphtypeDropDownLabel = uilabel(app.covid19);
            app.selectgraphtypeDropDownLabel.Text = 'select graph type';
            app.list = uidropdown(app.covid19);
            app.list.Items = {'select option', 'daily', 'total'};
            app.list.Tooltip = {'Daily data'; 'OR'; 'Cumulative(Total) Data'};
            app.list.Value = 'select option';
            app.l1 = uilabel(app.covid19);
            app.l1.Text = {'Data analysis of Coronavirus cases in Maharashtra'; ''};
            app.selectgraphtypeDropDownLabel_2 = uilabel(app.covid19);
            app.selectgraphtypeDropDownLabel_2.Text = 'select graph type';
            app.list2 = uidropdown(app.covid19);
            app.list2.Items = {'select option', 'line', 'bar'};
            app.list2.Tooltip = {'Line graph'; 'OR'; 'Bar graph'};
            app.list2.Value = 'select option';
            app.group = uibuttongroup(app.covid19);
            app.group.Tooltip = {'Select the category of graph to view'};
            app.group.Title = 'Select Category';
            app.ConfirmedButton = uiradiobutton(app.group);
            app.ConfirmedButton.Text = {'Confirmed'; ''};
            app.ConfirmedButton.Value = true;
            app.ActiveButton = uiradiobutton(app.group);
            app.ActiveButton.Text = {'Active'; ''};
            app.RecoveredButton = uiradiobutton(app.group);
            app.RecoveredButton.Text = {'Recovered'; ''};
            app.CasualtiesButton = uiradiobutton(app.group);
            app.CasualtiesButton.Text = 'Casualties';
            app.DisplayCitywiseButton = uibutton(app.covid19, 'state');
            app.DisplayCitywiseButton.ValueChangedFcn = createCallbackFcn(app, @DisplayCitywiseButtonValueChanged, true);
            app.DisplayCitywiseButton.Tooltip = {'press button to show citywise data'};
            app.DisplayCitywiseButton.Text = {'Display Citywise'; ''};
            app.TextArea = uitextarea(app.covid19);
            app.TextArea.Editable = 'off';
            app.Label = uilabel(app.covid19);
            app.ViewPunepageButton = uibutton(app.covid19, 'push');
            app.ViewPunepageButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPunepageButtonPushed, true);
            app.ViewPunepageButton.Text = 'View Pune page';
            % Show the figure after all components are created
            app.covid19.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid19mah

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.covid19)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.covid19)
        end
    end
end